classdef filtros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FILTROSPanel                    matlab.ui.container.Panel
        RuidoButton                     matlab.ui.control.Button
        RuidoButtonGroup                matlab.ui.container.ButtonGroup
        SaltandpepperButton             matlab.ui.control.ToggleButton
        GaussianoButton                 matlab.ui.control.ToggleButton
        ORIGINALLabel                   matlab.ui.control.Label
        RUIDOLabel                      matlab.ui.control.Label
        RESULTADOLabel                  matlab.ui.control.Label
        RuidoImage                      matlab.ui.control.Image
        OriginalImage                   matlab.ui.control.Image
        ImagenesdisponiblesButtonGroup  matlab.ui.container.ButtonGroup
        PeppersButton                   matlab.ui.control.ToggleButton
        FootballButton                  matlab.ui.control.ToggleButton
        MemeButton                      matlab.ui.control.ToggleButton
        ImagenButton                    matlab.ui.control.Button
        ResultadoMaxImage               matlab.ui.control.Image
        FiltradoButton                  matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        FiltrosButtonGroup              matlab.ui.container.ButtonGroup
        MedianaButton                   matlab.ui.control.ToggleButton
        ModaButton                      matlab.ui.control.ToggleButton
        MaxymInButton                   matlab.ui.control.ToggleButton
        MediaAritmeticaButton           matlab.ui.control.Button
        MediaGeometricaButton           matlab.ui.control.Button
        MediaArmonicaButton             matlab.ui.control.Button
        MCArmonicaButton                matlab.ui.control.Button
        ResultadoMinImage               matlab.ui.control.Image
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        DensidadEditFieldLabel          matlab.ui.control.Label
        DensidadEditField               matlab.ui.control.NumericEditField
        MediaEditFieldLabel             matlab.ui.control.Label
        MediaEditField                  matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel          matlab.ui.control.Label
        VarianzaEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        img_original
        img_ruido
        img_resultado1 
        img_resultado2 % Description
    end
    
    methods (Access = private)
        
        % tipoFiltro 
        % 1 = mediana
        % 2 = moda
        % 3 = max
        % 4 = min
        
        function img_resultado = filtradoImg(app,tipoFiltro)
            [filas,columnas,niveles]=size(app.img_ruido);
            img_resultado=zeros(filas,columnas,niveles,"uint8");
            img_roja = app.img_ruido(:,:,1);
            img_verde = app.img_ruido(:,:,2);
            img_azul = app.img_ruido(:,:,3);
            
            img_roja_f=iteracionPorDimensionFM(app,img_roja,tipoFiltro);
            img_verde_f=iteracionPorDimensionFM(app,img_verde,tipoFiltro);
            img_azul_f=iteracionPorDimensionFM(app,img_azul,tipoFiltro);

            img_resultado(:,:,1)=img_roja_f;
            img_resultado(:,:,2)=img_verde_f;
            img_resultado(:,:,3)=img_azul_f;
           
        end
         
        function imagenFiltrada = iteracionPorDimensionFM(app,imagen,tipoFiltro)
            [filas,columnas]=size(imagen);
            imagenFiltrada = zeros(filas,columnas,'uint8');
            for fila=1:filas
                for columna=1:columnas
                    mascara = crearMascara(app,imagen,fila,columna);
                    nuevoValor= ordenarMascara(app,mascara,tipoFiltro);
                    imagenFiltrada(fila,columna)=nuevoValor;
                end
            end
        end
        function mascara = crearMascara(app,imagen,fila,columna)
            mascara = zeros(1,9);
            mascara(1)=verificar_indice(app,imagen,fila-1,columna-1);
            mascara(2)=verificar_indice(app,imagen,fila-1,columna);
            mascara(3)=verificar_indice(app,imagen,fila-1,columna+1);
            mascara(4)=verificar_indice(app,imagen,fila,columna-1);
            mascara(5)=verificar_indice(app,imagen,fila,columna);
            mascara(6)=verificar_indice(app,imagen,fila,columna+1);
            mascara(7)=verificar_indice(app,imagen,fila+1,columna-1);
            mascara(8)=verificar_indice(app,imagen,fila+1,columna);
            mascara(9)=verificar_indice(app,imagen,fila+1,columna+1);
        end
        
        function valor = verificar_indice(app,imagen,fila,columna)
            try
                valor = imagen(fila, columna);
            catch
                valor = 0;
            end
        end
       
        
        function nuevoValor = ordenarMascara(app,mascara,tipoFiltro)
            if tipoFiltro==2 %el filtro de la moda no requiere ordenamiento
                nuevoValor=uint8(moda(app,mascara));
            else
                mascaraOrdenada=mascara;
                [d,n]=size(mascaraOrdenada);
                for i=2:n
                    for j=i:-1:2 
                        if mascaraOrdenada(j)<mascaraOrdenada(j-1)
                            temp=mascaraOrdenada(j-1);
                            mascaraOrdenada(j-1)=mascaraOrdenada(j);
                            mascaraOrdenada(j)=temp;
                        end
                    end
                end
                
                switch tipoFiltro
                    case 1 %mediana
                        nuevoValor=uint8(mascaraOrdenada(5));
                    case 3 %max
                        nuevoValor=uint8(mascaraOrdenada(9));
                    case 4 %min
                        nuevoValor=uint8(mascaraOrdenada(1));
                    case 5 %media aritmetica
                        nuevoValor=uint8(round(sum(mascaraOrdenada)/size(mascaraOrdenada)));
                    case 6 %media geometrica
                        producto=1;
                        for elemento = mascaraOrdenada
                            producto=elemento*producto;
                        end
                        nuevoValor=uint8(round(producto^(-size(mascaraOrdenada))));
                    case 7 %media armonica
                        suma = 0;
                        for elemento = mascaraOrdenada
                            suma=(1/elemento)+suma;
                        end
                        nuevoValor=uint8(round(size(mascaraOrdenada)/suma));
                    case 8 %contraarmonica
                        suma=sum(mascaraOrdenada);
                        R=-0.8; %valores positivos elimina ruido pimienta, negativos tipo sal
                        nuevoValor=uint8(round(suma^(R+1) / suma^R));
                end
                
           end
        end
        
        
        function valorModa = moda(app,mascara)
            frecuencias=zeros(1,26);
            for n=1:9
                if mascara(n)>=1 && mascara(n)<=9
                    frecuencias(1)=frecuencias(1)+1;
                elseif mascara(n)>=10 && mascara(n)<=19
                    frecuencias(2)=frecuencias(2)+1; 
                elseif mascara(n)>=20 && mascara(n)<=29
                    frecuencias(3)=frecuencias(3)+1;    
                elseif mascara(n)>=30 && mascara(n)<=39
                    frecuencias(4)=frecuencias(4)+1;
                elseif mascara(n)>=40 && mascara(n)<=49
                    frecuencias(5)=frecuencias(5)+1; 
                elseif mascara(n)>=50 && mascara(n)<=59
                    frecuencias(6)=frecuencias(6)+1; 
                elseif mascara(n)>=60 && mascara(n)<=69
                    frecuencias(7)=frecuencias(7)+1; 
                elseif mascara(n)>=70 && mascara(n)<=79
                    frecuencias(8)=frecuencias(8)+1;               
                elseif mascara(n)>=80 && mascara(n)<=89
                    frecuencias(9)=frecuencias(9)+1;   
                elseif mascara(n)>=90 && mascara(n)<=99
                    frecuencias(10)=frecuencias(10)+1;    
                elseif mascara(n)>=100 && mascara(n)<=109
                    frecuencias(11)=frecuencias(11)+1;   
                elseif mascara(n)>=110 && mascara(n)<=119
                    frecuencias(12)=frecuencias(12)+1;   
                elseif mascara(n)>=120 && mascara(n)<=129
                    frecuencias(13)=frecuencias(13)+1;    
                elseif mascara(n)>=130 && mascara(n)<=139
                    frecuencias(14)=frecuencias(14)+1;    
                elseif mascara(n)>=140 && mascara(n)<=149
                    frecuencias(15)=frecuencias(15)+1;   
                elseif mascara(n)>=150 && mascara(n)<=159
                    frecuencias(16)=frecuencias(16)+1;   
                elseif mascara(n)>=160 && mascara(n)<=169
                    frecuencias(17)=frecuencias(17)+1;   
                elseif mascara(n)>=170 && mascara(n)<=179
                    frecuencias(18)=frecuencias(18)+1;    
                elseif mascara(n)>=180 && mascara(n)<=189
                    frecuencias(19)=frecuencias(19)+1;    
                elseif mascara(n)>=190 && mascara(n)<=199
                    frecuencias(20)=frecuencias(20)+1;     
                elseif mascara(n)>=200 && mascara(n)<=209
                    frecuencias(21)=frecuencias(21)+1;   
                elseif mascara(n)>=210 && mascara(n)<=219
                    frecuencias(22)=frecuencias(22)+1;    
                elseif mascara(n)>=220 && mascara(n)<=229
                    frecuencias(23)=frecuencias(23)+1;    
                elseif mascara(n)>=230 && mascara(n)<=239
                    frecuencias(24)=frecuencias(24)+1;   
                elseif mascara(n)>=240 && mascara(n)<=249   
                    frecuencias(25)=frecuencias(25)+1;    
                elseif mascara(n)>=250 && mascara(n)<=259
                    frecuencias(26)=frecuencias(26)+1;
                end      
            end
            mayorFrec=0;
            for i=1:26
                if frecuencias(i)>mayorFrec
                    mayorFrec=i;
                end
            end
            
            valorModa=mayorFrec*10 - 5; 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RuidoButton
        function RuidoButtonPushed(app, event)
            % Ruido salt and pepper
            if app.SaltandpepperButton.Value
                app.img_ruido=imnoise(app.img_original,'salt & pepper',app.DensidadEditField.Value); 
                app.RuidoImage.ImageSource=app.img_ruido; 
                app.FiltradoButton.Enable='on';
                app.RuidoButton.Enable='off';
            elseif app.GaussianoButton.Value
                app.img_ruido=imnoise(app.img_original,'gaussian',app.MediaEditField.Value,app.VarianzaEditField.Value);
                app.RuidoImage.ImageSource=app.img_ruido; 
                app.FiltradoButton.Enable='on';
                app.RuidoButton.Enable='off';
            end
        end

        % Button pushed function: ImagenButton
        function ImagenButtonPushed(app, event)
            if app.PeppersButton.Value
                app.img_original= imread('peppers.png');
            elseif app.FootballButton.Value
                app.img_original= imread('football.jpg');
            elseif app.MemeButton.Value
                app.img_original = imread('meme.jpg');
            end
            app.OriginalImage.ImageSource=app.img_original;                             
            app.RuidoButton.Enable='on';
            app.ImagenButton.Enable='off';
        end

        % Button pushed function: FiltradoButton
        function FiltradoButtonPushed(app, event)
            if app.MedianaButton.Value
                app.img_resultado1=filtradoImg(app,1);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            elseif app.ModaButton.Value
                app.img_resultado1=filtradoImg(app,2);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            elseif app.MaxymInButton.Value
                app.img_resultado1=filtradoImg(app,3);
                app.img_resultado2=filtradoImg(app,4);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado2;
            elseif app.MediaAritmeticaButton.Value
                app.img_resultado1=filtradoImg(app,5);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            elseif app.MediaGeometricaButton.Value
                app.img_resultado1=filtradoImg(app,6);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            elseif app.MediaArmonicaButton.Value
                app.img_resultado1=filtradoImg(app,7);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            elseif app.MCArmonicaButton.Value
                app.img_resultado1=filtradoImg(app,8);
                app.ResultadoMaxImage.ImageSource=app.img_resultado1;
                app.ResultadoMinImage.ImageSource=app.img_resultado1;
            end
            app.FiltradoButton.Enable='off';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            null_img=zeros(300,300,3);
            app.OriginalImage.ImageSource=null_img; 
            app.RuidoImage.ImageSource=null_img;
            app.ResultadoMaxImage.ImageSource=null_img;
            app.ResultadoMinImage.ImageSource=null_img;
            app.ImagenButton.Enable='on';
            app.RuidoButton.Enable='off';
            app.FiltradoButton.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FILTROSPanel = uipanel(app.UIFigure);
            app.FILTROSPanel.Title = 'FILTROS';
            app.RuidoButton = uibutton(app.FILTROSPanel, 'push');
            app.RuidoButton.ButtonPushedFcn = createCallbackFcn(app, @RuidoButtonPushed, true);
            app.RuidoButton.Enable = 'off';
            app.RuidoButton.Text = 'Ruido';
            app.RuidoButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.RuidoButtonGroup.Title = 'Ruido';
            app.SaltandpepperButton = uitogglebutton(app.RuidoButtonGroup);
            app.SaltandpepperButton.Text = 'Salt and pepper';
            app.SaltandpepperButton.Value = true;
            app.GaussianoButton = uitogglebutton(app.RuidoButtonGroup);
            app.GaussianoButton.Text = 'Gaussiano';
            app.ORIGINALLabel = uilabel(app.FILTROSPanel);
            app.ORIGINALLabel.Text = 'ORIGINAL';
            app.RUIDOLabel = uilabel(app.FILTROSPanel);
            app.RUIDOLabel.Text = 'RUIDO';
            app.RESULTADOLabel = uilabel(app.FILTROSPanel);
            app.RESULTADOLabel.Text = 'RESULTADO';
            app.RuidoImage = uiimage(app.FILTROSPanel);
            app.OriginalImage = uiimage(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.ImagenesdisponiblesButtonGroup.Title = 'Imagenes disponibles';
            app.PeppersButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.PeppersButton.Text = 'Peppers';
            app.PeppersButton.Value = true;
            app.FootballButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.FootballButton.Text = 'Football';
            app.MemeButton = uitogglebutton(app.ImagenesdisponiblesButtonGroup);
            app.MemeButton.Text = 'Meme';
            app.ImagenButton = uibutton(app.FILTROSPanel, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenButtonPushed, true);
            app.ImagenButton.Text = 'Imagen';
            app.ResultadoMaxImage = uiimage(app.FILTROSPanel);
            app.FiltradoButton = uibutton(app.FILTROSPanel, 'push');
            app.FiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @FiltradoButtonPushed, true);
            app.FiltradoButton.Enable = 'off';
            app.FiltradoButton.Text = 'Filtrado';
            app.RESETButton = uibutton(app.FILTROSPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.FiltrosButtonGroup = uibuttongroup(app.FILTROSPanel);
            app.FiltrosButtonGroup.Title = 'Filtros';
            app.MedianaButton = uitogglebutton(app.FiltrosButtonGroup);
            app.MedianaButton.Text = 'Mediana';
            app.MedianaButton.Value = true;
            app.ModaButton = uitogglebutton(app.FiltrosButtonGroup);
            app.ModaButton.Text = 'Moda';
            app.MaxymInButton = uitogglebutton(app.FiltrosButtonGroup);
            app.MaxymInButton.Text = 'Max y mIn';
            app.MediaAritmeticaButton = uibutton(app.FiltrosButtonGroup, 'push');
            app.MediaAritmeticaButton.Text = 'M. Aritmetica';
            app.MediaGeometricaButton = uibutton(app.FiltrosButtonGroup, 'push');
            app.MediaGeometricaButton.Text = 'M. Geometrica';
            app.MediaArmonicaButton = uibutton(app.FiltrosButtonGroup, 'push');
            app.MediaArmonicaButton.Text = 'M. Armonica';
            app.MCArmonicaButton = uibutton(app.FiltrosButtonGroup, 'push');
            app.MCArmonicaButton.Text = 'M.C. Armonica';
            app.ResultadoMinImage = uiimage(app.FILTROSPanel);
            app.MaxLabel = uilabel(app.FILTROSPanel);
            app.MaxLabel.Text = 'Max';
            app.MinLabel = uilabel(app.FILTROSPanel);
            app.MinLabel.Text = 'Min';
            app.DensidadEditFieldLabel = uilabel(app.FILTROSPanel);
            app.DensidadEditFieldLabel.Text = 'Densidad';
            app.DensidadEditField = uieditfield(app.FILTROSPanel, 'numeric');
            app.MediaEditFieldLabel = uilabel(app.FILTROSPanel);
            app.MediaEditFieldLabel.Text = 'Media';
            app.MediaEditField = uieditfield(app.FILTROSPanel, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.FILTROSPanel);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.FILTROSPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end