classdef AppPractica6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImagenOriginalLabel  matlab.ui.control.Label
        HLabel               matlab.ui.control.Label
        LmLabel              matlab.ui.control.Label
        nLabel               matlab.ui.control.Label
        bitsLabel            matlab.ui.control.Label
        smboloLabel          matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Entropia             matlab.ui.control.NumericEditField
        LongitudMedia        matlab.ui.control.NumericEditField
        Eficiencia           matlab.ui.control.NumericEditField
        HistogramaLabel      matlab.ui.control.Label
        MinEditFieldLabel    matlab.ui.control.Label
        MinEditField         matlab.ui.control.NumericEditField
        MaxEditFieldLabel    matlab.ui.control.Label
        MaxEditField         matlab.ui.control.NumericEditField
        BuscarButton         matlab.ui.control.Button
        IntervaloLabel       matlab.ui.control.Label
        NumeroMax            matlab.ui.control.Label
        BitsMax              matlab.ui.control.Label
        NumeroMin            matlab.ui.control.Label
        BitsMin              matlab.ui.control.Label
        MximoLabel           matlab.ui.control.Label
        MnimoLabel           matlab.ui.control.Label
        grisLabel            matlab.ui.control.Label
        grisLabel_2          matlab.ui.control.Label
        smboloLabel_2        matlab.ui.control.Label
        smboloLabel_3        matlab.ui.control.Label
        ImagenOriginal       matlab.ui.control.UIAxes
        Histograma           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagen
        alto
        ancho 
        lista_etapas = {}
        lista_bits = {}
        lista
    end
    
    methods (Access = private)
        function actualizarImagenOriginal(app, nombre)
            app.imagen = imread(nombre);
            app.imagen = rgb2gray(app.imagen);
            
            imagesc(app.ImagenOriginal,app.imagen);
            
            % Tamaño de la imagen
            [app.alto, app.ancho] = size(app.imagen);
            
            % histograma
            histogram(app.Histograma, app.imagen);
            app.lista = ocurrences(app,app.imagen, app.alto, app.ancho);
            sumar_lista(app,[app.lista.p]);
            app.lista_etapas(end+1)={[app.lista.n]};
            app.lista_bits = relBits(app,app.lista_etapas);
            entropia = calcularEntropia(app,[app.lista.p]);
            longitudMedia = calcularLM(app,app.lista_bits);
            eficiencia = entropia/longitudMedia * 100;
            app.Eficiencia.Value = eficiencia;
            app.Entropia.Value = entropia;
            app.LongitudMedia.Value = longitudMedia;
        end
        function buscarIntervalo(app,min,max)
            auxMin = [];
            auxMax = [];
            intervalo = [0,0];
            aumento = 1;
            for i = min: +1: max
                for j = 1: +1: length(app.lista_bits)
                    if str2num(app.lista_bits(j,1)) == i
                        intervalo(aumento,1) = str2num(app.lista_bits(j,1));
                        intervalo(aumento,2) = strlength(app.lista_bits(j,3));
                        aumento = aumento +1;
                    end
                end
            end
            auxMax(1) = intervalo(1,1);
            auxMax(2) = intervalo(1,2)+1;
            auxMin(1) = intervalo(1,1);
            auxMin(2) = intervalo(1,2);
            for i = 1: +1: aumento-1
                if intervalo(i,2)>auxMax(2)
                    auxMax(1) = intervalo(i,1);
                    auxMax(2) = intervalo(i,2);
                elseif intervalo(i,2)<auxMin(2)
                    auxMin(1) = intervalo(i,1);
                    auxMin(2) = intervalo(i,2);
                end
            end
            
            app.NumeroMax.Text = num2str(auxMax(1));
            app.BitsMax.Text = num2str(auxMax(2));
            app.NumeroMin.Text = num2str(auxMin(1));
            app.BitsMin.Text = num2str(auxMin(2));
        end
        function resultado = calcularEntropia(app,lista)
            aux = 0;
            for item=lista
                aux = aux + ecuacionEntropia(app,item); 
            end
            resultado = aux;
        end
        
        function resultado = ecuacionEntropia(app,probabilidad)
            X = probabilidad ^ (-1);
            resultado = probabilidad * (log2(X));
            resultado = round(resultado, 2);
        end
        function resultado = calcularLM(app,lista)
            resultado = 0.0;
            for i = 1: +1: size(lista)
                resultado = resultado + ecuacion(app,str2double(lista(i,2)),strlength(lista(i,3)));
            end   
        end
        
        function resultado = ecuacion(app,x,y)
            resultado = x * y;
        end
        function resultado = ocurrences(app,imagen, alto, ancho)
            lista = [];
            cantidad = [];
            probabilidad = [];
            index = 1;
            
            for i = 1: +1 : alto
                for j = 1: +1: ancho
                    if ~ismember(imagen(i,j), lista)
                        lista(index) = imagen(i,j);
                        index = index + 1;
                    end
                end
            end
            lista = sort(lista);
            index = 1;
            for item = lista
               contador = 0;
               for i=1: +1 : alto
                   for j=1 : +1 :ancho
                       if imagen(i,j) == item
                           contador = contador + 1;
                       end
                   end
               end
               cantidad(index) = contador;
               probabilidad(index) = contador / (alto*ancho);
               index = index + 1;
            end 
            y = 1;
            listaOrdenada = [];
            for item = probabilidad
                if ~ismember(item, listaOrdenada)
                    listaOrdenada(y) = item;
                    y = y + 1;
                end
            end
            listaOrdenada = sort(listaOrdenada,'descend');
            
            n = 1;
            for item = listaOrdenada
                aux = [];
                aumento = 1;
                for x = index-1: -1: 1
                    if item == probabilidad(x)
                        aux(aumento) = x;
                        aumento = aumento + 1;
                    end
                end
                for x = 1: +1 : aumento -1 
                    data(n).n = lista(aux(x));
                    data(n).o = cantidad(aux(x));
                    data(n).p = probabilidad(aux(x));
                    n = n + 1;
                end
            end
            resultado = data;
        end
        function [resultado, index] = sumar_lista(app,lista)
            lista_ordenada = sort(lista,'descend');
            lista_aux = lista_ordenada;
            longitud = length(lista_ordenada);
            
            if longitud > 2
                suma = lista_ordenada(longitud-1) + lista_ordenada(longitud);
                
                lista_aux(longitud-1) = suma;
                lista_aux(end) = [];
                
                [resultado, index] = sumar_lista(app,lista_aux);
                index = index + 1;
            else
                index = 1;
                resultado = lista_ordenada;
            end
            x = getGlobalList(app);
            x(end+1) = {lista_ordenada};
            setGlobalList(app,x);
        end
        function setGlobalList(app,val)
            app.lista_etapas = val;
        end
        
        function r = getGlobalList(app)
            r = app.lista_etapas;
        end
        function resultado = relBits(app,lista)
            ultima = lista{1};
            c = ["",""];
            c(1,1) = num2str(ultima(end-1));
            c(1,2) = "1";
            c(2,1) = num2str(ultima(end));
            c(2,2) = "0";
            for i = 2: +1 : length(lista)-1
                aumento = 1;
                aux = ["",""];
                %Paso 1: Leer ultima fila
                ultima = lista{i};
                for item = ultima
                    aux(aumento,1) = num2str(item);
                    aumento = aumento + 1;
                end
                aux(end-1,2) = c(end-1,2);
                aux(end,2) = c(end,2);
                %Paso 2: Encontrar suma
                sum = num2str(ultima(end-1) + ultima(end));
                for j = 1: +1: length(c)
                    if strcmp(sum,c(j,1))
                        match = j;
                    end
                end
                %Paso 3: Leer ultimo bit y asignar en la ultima fila
                bit = split(c(match,2));
                
                if strcmp(bit(1),"1")
                    aux(end-1,2) = strcat ("1 ", aux(end-1,2));
                    aux(end,2) = strcat ("1 ", aux(end,2));
                else
                    aux(end-1,2) = strcat ("0 ", aux(end-1,2));
                    aux(end,2) = strcat ("0 ", aux(end,2));
                end
                %Paso 4: Buscar elementos faltantes en la anterior fila
                for i = 1: +1: length(aux)-2
                    for j = 1: +1: length(c)
                        if strcmp(aux(i,1),c(j,1))
                            aux(i,2) = c(j,2);
                            c(j,1) = "0";
                            break
                        end
                    end
                end
                c = aux;
            end
            resultado = ["","",""];
            numero = lista{end};
            for i = 1: +1: length(c)
                resultado(i,1) = num2str(numero(i));
                resultado(i,2) = c(i,1);
                aux = split(c(i,2));
                string = "";
                for j = 1: +1:length(aux)
                    string = strcat(string,aux(j));
                end
                resultado(i,3) = string;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImagenOriginal.Visible = 'off';
            app.ImagenOriginal.Colormap = gray(256);
            actualizarImagenOriginal(app,'peppers.png')
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            buscarIntervalo(app,app.MinEditField.Value,app.MaxEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagenOriginalLabel = uilabel(app.UIFigure);
            app.ImagenOriginalLabel.Text = 'Imagen Original';
            app.HLabel = uilabel(app.UIFigure);
            app.HLabel.Text = 'H =';
            app.LmLabel = uilabel(app.UIFigure);
            app.LmLabel.Text = 'Lm =';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'n =';
            app.bitsLabel = uilabel(app.UIFigure);
            app.bitsLabel.Text = 'bits';
            app.smboloLabel = uilabel(app.UIFigure);
            app.smboloLabel.Text = 'símbolo';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.Entropia = uieditfield(app.UIFigure, 'numeric');
            app.Entropia.Editable = 'off';
            app.LongitudMedia = uieditfield(app.UIFigure, 'numeric');
            app.LongitudMedia.Editable = 'off';
            app.Eficiencia = uieditfield(app.UIFigure, 'numeric');
            app.Eficiencia.Editable = 'off';
            app.HistogramaLabel = uilabel(app.UIFigure);
            app.HistogramaLabel.Text = 'Histograma';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuscarButton = uibutton(app.UIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.IntervaloLabel = uilabel(app.UIFigure);
            app.IntervaloLabel.Text = 'Intervalo';
            app.NumeroMax = uilabel(app.UIFigure);
            app.NumeroMax.Text = '';
            app.BitsMax = uilabel(app.UIFigure);
            app.BitsMax.Text = '';
            app.NumeroMin = uilabel(app.UIFigure);
            app.NumeroMin.Text = '';
            app.BitsMin = uilabel(app.UIFigure);
            app.BitsMin.Text = '';
            app.MximoLabel = uilabel(app.UIFigure);
            app.MximoLabel.Text = 'Máximo:';
            app.MnimoLabel = uilabel(app.UIFigure);
            app.MnimoLabel.Text = 'Mínimo:';
            app.grisLabel = uilabel(app.UIFigure);
            app.grisLabel.Text = 'gris';
            app.grisLabel_2 = uilabel(app.UIFigure);
            app.grisLabel_2.Text = 'gris';
            app.smboloLabel_2 = uilabel(app.UIFigure);
            app.smboloLabel_2.Text = 'símbolo';
            app.smboloLabel_3 = uilabel(app.UIFigure);
            app.smboloLabel_3.Text = 'símbolo';
            app.ImagenOriginal = uiaxes(app.UIFigure);
            app.ImagenOriginal.XTick = [];
            app.ImagenOriginal.XTickLabel = {'[]'};
            app.ImagenOriginal.YTick = [];
            app.Histograma = uiaxes(app.UIFigure);
            title(app.Histograma, 'Gris')
            xlabel(app.Histograma, 'Intensidad')
            ylabel(app.Histograma, 'Pixeles')
            app.Histograma.XLim = [0 255];
            app.Histograma.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppPractica6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end