classdef memorama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        JUGARButton            matlab.ui.control.Button
        MEMORAMALabel          matlab.ui.control.Label
        JugadorEditField       matlab.ui.control.EditField
        JugadorEditFieldLabel  matlab.ui.control.Label
        AgregarButton          matlab.ui.control.Button
        TurnoLabel             matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        UITable2               matlab.ui.control.Table
        RightPanel             matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image4                 matlab.ui.control.Image
        Image5                 matlab.ui.control.Image
        Image6                 matlab.ui.control.Image
        Image7                 matlab.ui.control.Image
        Image8                 matlab.ui.control.Image
        Image9                 matlab.ui.control.Image
        Image10                matlab.ui.control.Image
        Image11                matlab.ui.control.Image
        Image12                matlab.ui.control.Image
        Image13                matlab.ui.control.Image
        Image14                matlab.ui.control.Image
        Image15                matlab.ui.control.Image
        Image16                matlab.ui.control.Image
        Image17                matlab.ui.control.Image
        Image18                matlab.ui.control.Image
        Image19                matlab.ui.control.Image
        Image20                matlab.ui.control.Image
        Image21                matlab.ui.control.Image
        Image22                matlab.ui.control.Image
        Image23                matlab.ui.control.Image
        Image24                matlab.ui.control.Image
        Image25                matlab.ui.control.Image
        Image26                matlab.ui.control.Image
        Image27                matlab.ui.control.Image
        Image28                matlab.ui.control.Image
        Image29                matlab.ui.control.Image
        Image30                matlab.ui.control.Image
        Image31                matlab.ui.control.Image
        Image32                matlab.ui.control.Image
        Image33                matlab.ui.control.Image
        Image34                matlab.ui.control.Image
        Image35                matlab.ui.control.Image
        Image36                matlab.ui.control.Image
        Image37                matlab.ui.control.Image
        Image38                matlab.ui.control.Image
        Image39                matlab.ui.control.Image
        Image40                matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        turno = 1;
        jugadores = {} % Description
        jugador_actual = '' % Description
        carta_seleccionada = 1;
        cartas = [];
        id_primera_carta = 0;
        imgObjetos =[];
        bandera_puntuacion = 2;
    end
    
    methods (Access = public)
        function set_turno(app)
            if app.carta_seleccionada == 1
                app.carta_seleccionada = 2;
            elseif app.carta_seleccionada == 2
                app.carta_seleccionada = 1;
                [rows, cols] = size(app.jugadores);
                if(app.turno < rows)
                    app.turno = app.turno + 1;
                else
                    app.turno = 1;
                end 
            end
            app.jugador_actual = app.jugadores(app.turno);
            disp('Turno de ' + app.jugador_actual)
            app.Label.Text = app.jugador_actual;
        end
        
        function habilitar_imagenes(app)
            set(app.Image, 'Enable', 'on')
            set(app.Image2, 'Enable', 'on')
            set(app.Image3, 'Enable', 'on')
            set(app.Image4, 'Enable', 'on')
            set(app.Image5, 'Enable', 'on')
            set(app.Image6, 'Enable', 'on')
            set(app.Image7, 'Enable', 'on')
            set(app.Image8, 'Enable', 'on')
            set(app.Image9, 'Enable', 'on')
            set(app.Image10, 'Enable', 'on')
            set(app.Image11, 'Enable', 'on')
            set(app.Image12, 'Enable', 'on')
            set(app.Image13, 'Enable', 'on')
            set(app.Image14, 'Enable', 'on')
            set(app.Image15, 'Enable', 'on')
            set(app.Image16, 'Enable', 'on')
            set(app.Image17, 'Enable', 'on')
            set(app.Image18, 'Enable', 'on')
            set(app.Image19, 'Enable', 'on')
            set(app.Image20, 'Enable', 'on')
            set(app.Image21, 'Enable', 'on')
            set(app.Image22, 'Enable', 'on')
            set(app.Image23, 'Enable', 'on')
            set(app.Image24, 'Enable', 'on')
            set(app.Image25, 'Enable', 'on')
            set(app.Image26, 'Enable', 'on')
            set(app.Image27, 'Enable', 'on')
            set(app.Image28, 'Enable', 'on')
            set(app.Image29, 'Enable', 'on')
            set(app.Image30, 'Enable', 'on')
            set(app.Image31, 'Enable', 'on')
            set(app.Image32, 'Enable', 'on')
            set(app.Image33, 'Enable', 'on')
            set(app.Image34, 'Enable', 'on')
            set(app.Image35, 'Enable', 'on')
            set(app.Image36, 'Enable', 'on')
            set(app.Image37, 'Enable', 'on')
            set(app.Image38, 'Enable', 'on')
            set(app.Image39, 'Enable', 'on')
            set(app.Image40, 'Enable', 'on')
        end
        
        function inicializar_memorama(app)
            % leemos las imagenes
            leer_archivos = dir('./cartas/*.png');
            for k = 1:length(leer_archivos) %recorre número de archivos guardados en el directorio
                archivo = strcat('./cartas/', leer_archivos(k).name);
                imagen = imread(archivo);% lee las imagenes
                carta.id = k;
                carta.name = archivo;
                carta.image = imagen;
                carta.histogram = getHistogram(app,imagen);
                carta.nobjetos = getNobjects(app,imagen);
                carta.estado = 0; % oculta
                app.cartas = [app.cartas, carta];
            end
            
            % Duplicar cartas
            for carta=app.cartas
                carta.id = carta.id + 20;
                app.cartas = [app.cartas, carta];
            end
            
            % arreglar esto para que se posicionen dinamicamente
             
%             app.Image.ImageSource = app.cartas(1).name;
%             app.Image2.ImageSource = app.cartas(2).name;
%             app.Image3.ImageSource = app.cartas(3).name;
%             app.Image4.ImageSource = app.cartas(4).name;
%             app.Image5.ImageSource = app.cartas(5).name;
%             app.Image6.ImageSource = app.cartas(6).name;
%             app.Image7.ImageSource = app.cartas(7).name;
%             app.Image8.ImageSource = app.cartas(8).name;
%             app.Image9.ImageSource = app.cartas(9).name;
%             app.Image10.ImageSource = app.cartas(10).name;
%             app.Image11.ImageSource = app.cartas(11).name;
%             app.Image12.ImageSource = app.cartas(12).name;
%             app.Image13.ImageSource = app.cartas(13).name;
%             app.Image14.ImageSource = app.cartas(14).name;
%             app.Image15.ImageSource = app.cartas(15).name;
%             app.Image16.ImageSource = app.cartas(16).name;
%             app.Image17.ImageSource = app.cartas(17).name;
%             app.Image18.ImageSource = app.cartas(18).name;
%             app.Image19.ImageSource = app.cartas(19).name;
%             app.Image20.ImageSource = app.cartas(20).name;
%             app.Image21.ImageSource = app.cartas(21).name;
%             app.Image22.ImageSource = app.cartas(22).name;
%             app.Image23.ImageSource = app.cartas(23).name;
%             app.Image24.ImageSource = app.cartas(24).name;
%             app.Image25.ImageSource = app.cartas(25).name;
%             app.Image26.ImageSource = app.cartas(26).name;
%             app.Image27.ImageSource = app.cartas(27).name;
%             app.Image28.ImageSource = app.cartas(28).name;
%             app.Image29.ImageSource = app.cartas(29).name;
%             app.Image30.ImageSource = app.cartas(30).name;
%             app.Image31.ImageSource = app.cartas(31).name;
%             app.Image32.ImageSource = app.cartas(32).name;
%             app.Image33.ImageSource = app.cartas(33).name;
%             app.Image34.ImageSource = app.cartas(34).name;
%             app.Image35.ImageSource = app.cartas(35).name;
%             app.Image36.ImageSource = app.cartas(36).name;
%             app.Image37.ImageSource = app.cartas(37).name;
%             app.Image38.ImageSource = app.cartas(38).name;
%             app.Image39.ImageSource = app.cartas(39).name;
%             app.Image40.ImageSource = app.cartas(40).name;
        end
        
        function actualizar_carta(app, id)
            app.cartas(id).estado = 1;
            disp(strcat('Carta: ', string(app.cartas(id).id)))
            disp(strcat('Estado: ', string(app.cartas(id).estado)))
        end
        
        function ocultar_imagen(app, id)
            app.cartas(id).estado = 0;
            name = '';
            switch id
                case 1
                    app.Image.ImageSource = name;
                case 2
                    app.Image2.ImageSource = name;
                case 3
                    app.Image3.ImageSource = name;
                case 4
                    app.Image4.ImageSource = name;
                case 5
                    app.Image5.ImageSource = name;
                case 6
                    app.Image6.ImageSource = name;
                case 7
                    app.Image7.ImageSource = name;
                case 8
                    app.Image8.ImageSource = name;
                case 9
                    app.Image9.ImageSource = name;
                case 10
                    app.Image10.ImageSource = name;
                case 11
                    app.Image11.ImageSource = name;
                case 12
                    app.Image12.ImageSource = name;
                case 13
                    app.Image13.ImageSource = name;
                case 14
                    app.Image14.ImageSource = name;
                case 15
                    app.Image15.ImageSource = name;
                case 16
                    app.Image16.ImageSource = name;
                case 17
                    app.Image17.ImageSource = name;
                case 18
                    app.Image18.ImageSource = name;
                case 19
                    app.Image19.ImageSource = name;
                case 20
                    app.Image20.ImageSource = name;
                case 21
                    app.Image21.ImageSource = name;
                case 22
                    app.Image22.ImageSource = name;
                case 23
                    app.Image23.ImageSource = name;
                case 24
                    app.Image24.ImageSource = name;
                case 25
                    app.Image25.ImageSource = name;
                case 26
                    app.Image26.ImageSource = name;
                case 27
                    app.Image27.ImageSource = name;
                case 28
                    app.Image28.ImageSource = name;
                case 29
                    app.Image29.ImageSource = name;
                case 30
                    app.Image30.ImageSource = name;
                case 31
                    app.Image31.ImageSource = name;
                case 32
                    app.Image32.ImageSource = name;
                case 33
                    app.Image33.ImageSource = name;
                case 34
                    app.Image34.ImageSource = name;
                case 35
                    app.Image35.ImageSource = name;
                case 36
                    app.Image36.ImageSource = name;
                case 37
                    app.Image37.ImageSource = name;
                case 38
                    app.Image38.ImageSource = name;
                case 39
                    app.Image39.ImageSource = name;
                case 40
                    app.Image40.ImageSource = name;
            end
        end
        
        function habilitar_imagen(app, id)
            switch id
                case 1
                    set(app.Image, 'Enable', 'on')
                case 2
                    set(app.Image2, 'Enable', 'on')
                case 3
                    set(app.Image3, 'Enable', 'on')
                case 4
                    set(app.Image4, 'Enable', 'on')
                case 5
                    set(app.Image5, 'Enable', 'on')
                case 6
                    set(app.Image6, 'Enable', 'on')
                case 7
                    set(app.Image7, 'Enable', 'on')
                case 8
                    set(app.Image8, 'Enable', 'on')
                case 9
                    set(app.Image9, 'Enable', 'on')
                case 10
                    set(app.Image10, 'Enable', 'on')
                case 11
                    set(app.Image11, 'Enable', 'on')
                case 12
                    set(app.Image12, 'Enable', 'on')
                case 13
                    set(app.Image13, 'Enable', 'on')
                case 14
                    set(app.Image14, 'Enable', 'on')
                case 15
                    set(app.Image15, 'Enable', 'on')
                case 16
                    set(app.Image16, 'Enable', 'on')
                case 17
                    set(app.Image17, 'Enable', 'on')
                case 18
                    set(app.Image18, 'Enable', 'on')
                case 19
                    set(app.Image19, 'Enable', 'on')
                case 20
                    set(app.Image20, 'Enable', 'on')
                case 21
                    set(app.Image21, 'Enable', 'on')
                case 22
                    set(app.Image22, 'Enable', 'on')
                case 23
                    set(app.Image23, 'Enable', 'on')
                case 24
                    set(app.Image24, 'Enable', 'on')
                case 25
                    set(app.Image25, 'Enable', 'on')
                case 26
                    set(app.Image26, 'Enable', 'on')
                case 27
                    set(app.Image27, 'Enable', 'on')
                case 28
                    set(app.Image28, 'Enable', 'on')
                case 29
                    set(app.Image29, 'Enable', 'on')
                case 30
                    set(app.Image30, 'Enable', 'on')
                case 31
                    set(app.Image31, 'Enable', 'on')
                case 32
                    set(app.Image32, 'Enable', 'on')
                case 33
                    set(app.Image33, 'Enable', 'on')
                case 34
                    set(app.Image34, 'Enable', 'on')
                case 35
                    set(app.Image35, 'Enable', 'on')
                case 36
                    set(app.Image36, 'Enable', 'on')
                case 37
                    set(app.Image37, 'Enable', 'on')
                case 38
                    set(app.Image38, 'Enable', 'on')
                case 39
                    set(app.Image39, 'Enable', 'on')
                case 40
                    set(app.Image40, 'Enable', 'on')
            end
        end
        
        function histograma = getHistogram(app, imagen) 
            img = imagen(:,:,1);
            [alto,ancho]=size(img);
            histograma = zeros(256,1);
            for i=1:alto
                for j=1:ancho
                    val = img(i,j)+1;
                    histograma(val,1)=histograma(val,1)+1;
                end
            end    
        end
        
        function res = compHistogram (app, hist1, hist2)
            res=0;
            for i=1:256
                if hist1(i,1)~=hist2(i,1)
                    res=-1;
                    break;
                end
            end   
        end

        function nObjects = getNobjects(app,imagen)
    
            img = imagen(:,:,1);
            imgBin = imbinarize(img);
            [alto,ancho]=size(imgBin);
            app.imgObjetos = zeros(alto,ancho);
            
            for i=1:alto
                for j=1:ancho
                    if imgBin(i,j)==1
                        app.imgObjetos(i,j)=1;
                    end 
                end
            end
            
            nObjects=2;
            
            for i=1:alto
                for j =1:ancho
                    if app.imgObjetos(i,j)==1
                        findImg(app,i,j,nObjects);
                        nObjects=nObjects+1;
                    end
                end
            end
            
            nObjects=nObjects-2;
            
        end
        
        function valor = findImg(app,x,y,val)
    
            app.imgObjetos(x,y)=val;
    
            a = verificar_indice(app,x-1,y-1);
            b = verificar_indice(app,x-1,y  );
            c = verificar_indice(app,x-1,y+1);
            d = verificar_indice(app,x  ,y-1);
            f = verificar_indice(app,x  ,y+1);
            g = verificar_indice(app,x+1,y-1);
            h = verificar_indice(app,x+1,y  );
            i = verificar_indice(app,x+1,y+1);
    
            if  a==1 
                findImg(app,x-1,y-1,val);
            end
            if b==1 
                findImg(app,x-1,y  ,val);
            end
            if c==1 
                findImg(app,x-1,y+1,val);
            end
            if d==1 
                findImg(app,x  ,y-1,val);
            end
            if f==1 
                findImg(app,x  ,y+1,val);
            end
            if g==1 
                findImg(app,x+1,y-1,val);
            end
            if h==1 
                findImg(app,x+1,y  ,val);
            end
            if i==1    
                findImg(app,x+1,y+1,val);
            end   
    
        end
        
        function resultado = verificar_indice(app,x,y)
            try
                resultado = app.imgObjetos(x, y);
            catch
                resultado = 0;
            end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JUGARButton
        function JUGARButtonPushed(app, event)
                      
            if(size(app.jugadores))
                set(app.AgregarButton, 'Enable', 'off')
                set(app.JUGARButton, 'Enable', 'off')
                app.JugadorEditField.Value = '';
                set(app.JugadorEditField, 'Enable', 'off')
                
                inicializar_memorama(app)
                
                habilitar_imagenes(app)
              
                app.jugador_actual = app.jugadores(app.turno);
                app.Label.Text = app.jugador_actual;
                disp('Empieza el jugador ' + app.jugador_actual)
                
            else
                disp('Agrega un jugador antes de iniciar!')
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            % Agreagr jugador a la tabla de puntuación
            if(app.JugadorEditField.Value)
                new_row = [string(app.JugadorEditField.Value), 0];
                old_data = get(app.UITable, 'Data');
                new_data = [old_data; new_row];
                set(app.UITable, 'Data', new_data)
                disp(new_data)
                app.JugadorEditField.Value = '';
                app.jugadores = get(app.UITable, 'Data');
            end
        end

        % Image clicked function: Image, Image10, Image11, Image12, 
        % Image13, Image14, Image15, Image16, Image17, Image18, 
        % Image19, Image2, Image20, Image21, Image22, Image23, 
        % Image24, Image25, Image26, Image27, Image28, Image29, 
        % Image3, Image30, Image31, Image32, Image33, Image34, 
        % Image35, Image36, Image37, Image38, Image39, Image4, 
        % Image40, Image5, Image6, Image7, Image8, Image9
        function ImageClicked(app, event)
            % aqui se debe limpiar la tabla de las imagenes para que se
            % pueda volver a pintar la info
            imagen_id = string(event.Source.Tag);
            disp(strcat('Click en imagen ', imagen_id))
            event.Source.ImageSource = app.cartas(double(imagen_id)).name;
            
            set(event.Source, 'Enable', 'off')
            disp(strcat('carta seleccionada:', string(app.carta_seleccionada)))
            
            if app.carta_seleccionada == 1
                app.id_primera_carta = app.cartas(double(imagen_id)).id;
                %aqui se debe actualizar la tabla de las imagenes con los
                %datos de la imagen 1
                

            end
            
            if(app.carta_seleccionada == 2)
                % validar que sean pares
                disp(strcat('1ra carta seleccionada:', string(app.cartas(app.id_primera_carta).id)))
                disp(strcat('2da carta seleccionada:', string(app.cartas(double(imagen_id)).id)))
                
                hist1 = app.cartas(app.id_primera_carta).histogram;
                hist2 = app.cartas(double(imagen_id)).histogram;
                
                
                nobjetos1 = app.cartas(app.id_primera_carta).nobjetos;
                nobjetos2 = app.cartas(double(imagen_id)).nobjetos;
                
                disp(nobjetos1);
                disp(nobjetos2);
                
                if compHistogram(app, hist1, hist2)==0 && nobjetos1==nobjetos2
                    disp('son iguales');
                    all_data = get(app.UITable, 'Data');
                    jugador = all_data(app.turno,1);
                    puntos = all_data(app.turno,2);
                    disp(strcat('jugador:', jugador))
                    disp(strcat('puntos:', puntos))
                    nuevos_puntos = double(puntos) + 10; % 10 puntos por par
                    all_data(app.turno,2) = string(nuevos_puntos);
                    set(app.UITable, 'Data', all_data)
                    %aqui se debe actualizar la tabla de las imagenes con los
                    %datos de la imagen 2
                    app.bandera_puntuacion = 1;
                    
                else
                    disp('no son iguales');
                    pause(1)
                    habilitar_imagen(app, app.cartas(app.id_primera_carta).id)
                    habilitar_imagen(app, app.cartas(double(imagen_id)).id)
                    ocultar_imagen(app, app.cartas(app.id_primera_carta).id)
                    ocultar_imagen(app, app.cartas(double(imagen_id)).id)
                    app.bandera_puntuacion = 0;
                    
                end
                
            end
            
            if(app.bandera_puntuacion == 2)
                set_turno(app)
            elseif(app.bandera_puntuacion == 1)
                app.carta_seleccionada = 1;
                app.bandera_puntuacion = 2;
            else 
                set_turno(app)
                app.bandera_puntuacion = 2;
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {749, 749};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Jugador'; 'Puntuación'};
            app.UITable.RowName = {};
            app.JUGARButton = uibutton(app.GridLayout3, 'push');
            app.JUGARButton.ButtonPushedFcn = createCallbackFcn(app, @JUGARButtonPushed, true);
            app.JUGARButton.Text = 'JUGAR';
            app.MEMORAMALabel = uilabel(app.GridLayout3);
            app.MEMORAMALabel.Text = 'MEMORAMA';
            app.JugadorEditField = uieditfield(app.GridLayout3, 'text');
            app.JugadorEditFieldLabel = uilabel(app.GridLayout3);
            app.JugadorEditFieldLabel.Text = 'Jugador';
            app.AgregarButton = uibutton(app.GridLayout3, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.TurnoLabel = uilabel(app.GridLayout3);
            app.TurnoLabel.Text = 'Turno:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'Imagen 1'; 'Imagen 2'};
            app.UITable2.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tag = '1';
            app.Image.Enable = 'off';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2.Tag = '2';
            app.Image2.Enable = 'off';
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image3.Tag = '3';
            app.Image3.Enable = 'off';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4.Tag = '4';
            app.Image4.Enable = 'off';
            app.Image5 = uiimage(app.GridLayout2);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image5.Tag = '5';
            app.Image5.Enable = 'off';
            app.Image6 = uiimage(app.GridLayout2);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image6.Tag = '6';
            app.Image6.Enable = 'off';
            app.Image7 = uiimage(app.GridLayout2);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image7.Tag = '7';
            app.Image7.Enable = 'off';
            app.Image8 = uiimage(app.GridLayout2);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image8.Tag = '8';
            app.Image8.Enable = 'off';
            app.Image9 = uiimage(app.GridLayout2);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image9.Tag = '9';
            app.Image9.Enable = 'off';
            app.Image10 = uiimage(app.GridLayout2);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image10.Tag = '10';
            app.Image10.Enable = 'off';
            app.Image11 = uiimage(app.GridLayout2);
            app.Image11.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image11.Tag = '11';
            app.Image11.Enable = 'off';
            app.Image12 = uiimage(app.GridLayout2);
            app.Image12.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image12.Tag = '12';
            app.Image12.Enable = 'off';
            app.Image13 = uiimage(app.GridLayout2);
            app.Image13.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image13.Tag = '13';
            app.Image13.Enable = 'off';
            app.Image14 = uiimage(app.GridLayout2);
            app.Image14.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image14.Tag = '14';
            app.Image14.Enable = 'off';
            app.Image15 = uiimage(app.GridLayout2);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image15.Tag = '15';
            app.Image15.Enable = 'off';
            app.Image16 = uiimage(app.GridLayout2);
            app.Image16.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image16.Tag = '16';
            app.Image16.Enable = 'off';
            app.Image17 = uiimage(app.GridLayout2);
            app.Image17.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image17.Tag = '17';
            app.Image17.Enable = 'off';
            app.Image18 = uiimage(app.GridLayout2);
            app.Image18.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image18.Tag = '18';
            app.Image18.Enable = 'off';
            app.Image19 = uiimage(app.GridLayout2);
            app.Image19.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image19.Tag = '19';
            app.Image19.Enable = 'off';
            app.Image20 = uiimage(app.GridLayout2);
            app.Image20.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image20.Tag = '20';
            app.Image20.Enable = 'off';
            app.Image21 = uiimage(app.GridLayout2);
            app.Image21.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image21.Tag = '21';
            app.Image21.Enable = 'off';
            app.Image22 = uiimage(app.GridLayout2);
            app.Image22.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image22.Tag = '22';
            app.Image22.Enable = 'off';
            app.Image23 = uiimage(app.GridLayout2);
            app.Image23.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image23.Tag = '23';
            app.Image23.Enable = 'off';
            app.Image24 = uiimage(app.GridLayout2);
            app.Image24.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image24.Tag = '24';
            app.Image24.Enable = 'off';
            app.Image25 = uiimage(app.GridLayout2);
            app.Image25.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image25.Tag = '25';
            app.Image25.Enable = 'off';
            app.Image26 = uiimage(app.GridLayout2);
            app.Image26.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image26.Tag = '26';
            app.Image26.Enable = 'off';
            app.Image27 = uiimage(app.GridLayout2);
            app.Image27.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image27.Tag = '27';
            app.Image27.Enable = 'off';
            app.Image28 = uiimage(app.GridLayout2);
            app.Image28.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image28.Tag = '28';
            app.Image28.Enable = 'off';
            app.Image29 = uiimage(app.GridLayout2);
            app.Image29.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image29.Tag = '29';
            app.Image29.Enable = 'off';
            app.Image30 = uiimage(app.GridLayout2);
            app.Image30.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image30.Tag = '30';
            app.Image30.Enable = 'off';
            app.Image31 = uiimage(app.GridLayout2);
            app.Image31.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image31.Tag = '31';
            app.Image31.Enable = 'off';
            app.Image32 = uiimage(app.GridLayout2);
            app.Image32.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image32.Tag = '32';
            app.Image32.Enable = 'off';
            app.Image33 = uiimage(app.GridLayout2);
            app.Image33.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image33.Tag = '33';
            app.Image33.Enable = 'off';
            app.Image34 = uiimage(app.GridLayout2);
            app.Image34.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image34.Tag = '34';
            app.Image34.Enable = 'off';
            app.Image35 = uiimage(app.GridLayout2);
            app.Image35.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image35.Tag = '35';
            app.Image35.Enable = 'off';
            app.Image36 = uiimage(app.GridLayout2);
            app.Image36.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image36.Tag = '36';
            app.Image36.Enable = 'off';
            app.Image37 = uiimage(app.GridLayout2);
            app.Image37.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image37.Tag = '37';
            app.Image37.Enable = 'off';
            app.Image38 = uiimage(app.GridLayout2);
            app.Image38.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image38.Tag = '38';
            app.Image38.Enable = 'off';
            app.Image39 = uiimage(app.GridLayout2);
            app.Image39.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image39.Tag = '39';
            app.Image39.Enable = 'off';
            app.Image40 = uiimage(app.GridLayout2);
            app.Image40.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image40.Tag = '40';
            app.Image40.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memorama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end